classdef MSAPP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        LoadDatasetButton               matlab.ui.control.Button
        Lamp                            matlab.ui.control.Lamp
        AcquiredatapointsButton         matlab.ui.control.Button
        TestspecimendimensionsLabel     matlab.ui.control.Label
        ThicknessmmEditFieldLabel       matlab.ui.control.Label
        ThicknessmmEditField            matlab.ui.control.NumericEditField
        WidthmmEditFieldLabel           matlab.ui.control.Label
        WidthmmEditField                matlab.ui.control.NumericEditField
        LengthmmEditFieldLabel          matlab.ui.control.Label
        LengthmmEditField               matlab.ui.control.NumericEditField
        stStepLabel                     matlab.ui.control.Label
        ndStepLabel                     matlab.ui.control.Label
        rdStepLabel                     matlab.ui.control.Label
        SpecimenDropDownLabel           matlab.ui.control.Label
        SpecimenDropDown                matlab.ui.control.DropDown
        DiametermmEditFieldLabel        matlab.ui.control.Label
        DiametermmEditField             matlab.ui.control.NumericEditField
        Lamp_2                          matlab.ui.control.Lamp
        Lamp_3                          matlab.ui.control.Lamp
        PlotDataButton                  matlab.ui.control.Button
        Lamp_4                          matlab.ui.control.Lamp
        ExtensometerengagedLabel        matlab.ui.control.Label
        ConversiontoStressStrainButton  matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        PlotRp01Button                  matlab.ui.control.Button
        PlotRp02Button                  matlab.ui.control.Button
        ClearplotButton                 matlab.ui.control.Button
        NewAnalysisButton               matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
    path
    filename
    Dataset
    F %load measurement
    T %time measurement
    dl %position measurement
    t %thickness of specimen
    b %width of specimen
    d %diameter of specimen
    L0 %Gauge length
    vector
    value
    value1
    n3 %last good point
    fs %sampling freq
    fn %Nyquist freq
    norm_data %data for normalization
    n2idxFH %n2 idx for filter heuristic
    k
    F_filter %filtered F data
    dl_filter %filtered dl data
    wlvalue %window length value for SG & MA
    F_filter_ma %filtered with MA F data
    dl_filter_ma %filtered with MA dl data
    F_filter_sg %filtered with SG F data
    dl_filter_sg %filtered with SG dl data
    n2idxSSF_ma %n2 idx for SS with MA
    n2_meand1_ma %n2 idx before mean for SS with MA
    n2idxSSF_sg %n2 idx for SS with SG
    n2_meand1_sg %n2 idx before mean for SS with SG
    lenF %length of data vector F
    n2_theo_points %all n2 indexed appended
    dimvalue%value used for indicators
    Stress%F converted to Stress
    Strain%dl converted to Strain
    pltval%value for plot
    A %surface area
    opt_rp_line
    slope%slope of the curve
    proof_line%single proof line
    proof_line01%0.1proof line
    proof_line02%0.2proof line
    pltrpval%value used for diagram plotting
    end
    
    methods (Access = private)
        
        function [value1] = Extensometer_on_test(app,vector)
            TF=isnan(vector);
            countNan=sum(TF(:)==1);
            per_of_NaN=countNan/length(vector);
            if per_of_NaN>0.1
                app.value1=0;
            else
            vector1=(vector(~isnan(vector)));%removing NaN values
            meanvector=mean(vector1);%mean of all the values after removing Nan
            answer = length(find ( vector1 < 0 ));%number of negative values from array
            per_of_values=(answer/length(vector1))*100;
                if meanvector<0 || per_of_values>50
                    value1=0;
                else 
                    value1=1;
                end
            end
        clear vector1 meanvector answer per_of_values TF countNan
        end
        
        function [F,dl,n3,fs,fn] = Data_preprocessing_v1(app,F,T,dl)
            F=F(1:(length(F)-1));
            dl=dl(1:(length(dl)-1));
            T=T(1:(length(T)-1));
            %% First point adjustment
            F=F-F(1);
            dl=dl-dl(1);
            %% Determaning n3 point when the specimen broke
            Fmax=max(F);
            for n=1:1:length(F)
                if(F(n)>(Fmax-(0.005*Fmax)))
                    n3=n;%n3-last point before test specimen failure
                end
            end
            n=0;
            %% Rewriting the data up to n3 point
            F=F(1:n3);
            dl=dl(1:n3);
            fs=(length(T))/(max(T));%calulating the sampling frequency
            fs=round(fs); %fs - sampling freq 
            fn=fs/2; %fn-niquist freq
            clear n 
        end
        
        function [norm_data,dl,F]=data_normalization(app,dl,F)
            norm_data=zeros(1,4);
            norm_data(1)=min(dl);
            norm_data(2)=max(dl);
            dl=(dl-norm_data(1))/(norm_data(2)-norm_data(1));
            %%
            norm_data(3)=min(F);
            norm_data(4)=max(F);
            F=(F-norm_data(3))/(norm_data(4)-norm_data(3));
        end
        
        function [n2idxFH] = Filter_heuristic_v1(app,F,dl,fs)
            app.n3=(length(F)-1);%n3-how much data do we need to take into account that division of length of data vector F and step is intiger
            while true
                [app.k]=app.check_division(app.n3);
                    if app.k==0
                        app.n3=app.n3-1;    
                    else
                        break
                    end
            end
            step=app.k;
            clear k
            %% Creating the array fs more - filter values in Hz from Niquist freq to value larger then 0
            len=10;%len=18
            fsmore=zeros(1,len);
            r=3;%r=3
                for ifs=1:1:len
            
                    fsmore(ifs)=fs./(ifs*r);
            
                end
            clear r ifs
            %% Rounding up fsmore values 
            
            fsmore=round(fsmore,2,'significant');
            
            %% Filter Heuristic
            for i=1:1:length(fsmore)
            
            %% Filtering all the values
            
                %% Lowpass data filtering 
            
                F2=lowpass(F,fsmore(i),fs);
                dl2=lowpass(dl,fsmore(i),fs);
            
                %% Derivative of the filtered data
                slope_F2=zeros((length(F2)-1),1);
                for n=2:1:length(F2)
                    
                    slope_F2(n-1)=(F2(n)-F2(n-1))./(dl2(n)-dl2(n-1));
                    
                end
                n=0;
            
                %% Averaging every "h-step" data
                
                Avg_slopeF2=zeros((app.n3/step),1);
                z=1;
                for h=1:step:(length(slope_F2)-step)
                    Avg_slopeF2(z)=mean(slope_F2(h:1:(h+step)));
                    z=z+1;
                end
                h=0;
                
                %% Finding points smaller then the mean value of derivative
                
                Mean_slope_F2=mean(slope_F2);
                indexnotation=zeros(length(fsmore),length(Avg_slopeF2));
                for i2=1:1:length(Avg_slopeF2)
                    
                    if Avg_slopeF2(i2)<Mean_slope_F2 
                        
                        indexnotation(i,i2)=i2;
                        
                    end
                end
                i2=0;
                
            end
            clear i2 i  Mean_slope_F2 h  slope_F2  dl2 Avg_slopeF2 F2 z n3
            
            %% Writing all the non zero values into nzer
            
            nzer=zeros((size(indexnotation,1)),(size(indexnotation,2)));
            
            for i=1:1:(size(indexnotation,1))
                for n=1:1:(size(indexnotation,2))
                    if (indexnotation(i,n)~=0)
                        nzer(i)=indexnotation(i,n);
                        break
                    end
                end
            end
            clear indexnotation i n 
            
            %% Taking into consideration only values larger then 10
            
            nzer=nzer(nzer>=10);
            
            %% Median of all the "good" values
            
            Median_nzer=median(nzer);
            clear nzer
            
            %% Real index
            
            n2idxFH=Median_nzer*step;%n2idxFH-n2 point for filter heuristic
            clear Median_nzer step
        end
        
        function [k]=check_division(app,n3)
            if (mod(n3,5)==0)%modulo funkcija(ako je ostatak djeljenja n2 i 5 = 0 k=5, vrijedi i za ostale)
                k=5;
            elseif (mod(n3,6)==0)
                k=6;
            elseif (mod(n3,7)==0)
                k=7;
            elseif (mod(n3,8)==0)
                k=8;
            elseif (mod(n3,9)==0)
                k=9;
            else
                k=0;
            end
        end
        
        function [F_filter,dl_filter]=MA_opt_filt(app,dl,F)
            Sp=0.9999999;
            %% MAvg filter
            %wlma-window length moving average, wlmax-maximum windows length
            h=length(app.F);
            if length(app.F)>10 && length(F)<1000
                wlmax=floor(length(F)/2);
                wlma=floor(wlmax/10):floor(wlmax/10):wlmax;
            elseif length(F)>=1000
                wlmax=floor(length(F)/10);
                wlma=floor(wlmax/10):floor(wlmax/10):wlmax;
            else
                wlma=1;
            end
            wlma=wlma';
            rmse_ma=zeros(1,length(wlma));
            idx_ma=zeros(1,length(wlma));
                for i=1:1:length(wlma)
                    
                    kernel=ones(wlma(i),1)/wlma(i);
                    F_filter=filter(kernel, 1, F);
                    dl_filter=filter(kernel, 1, dl);
                    rmse_ma(i)=(sqrt(mean((F-F_filter).^2)));
                    [xData, yData] = prepareCurveData(dl_filter, F_filter);
                    ft = fittype( 'smoothingspline' );
                    opts = fitoptions( 'Method', 'SmoothingSpline' );
                    opts.SmoothingParam=Sp;
                    [fitresult,~] = fit( xData, yData, ft, opts );
                    [~,d2] = differentiate(fitresult,dl);
                    idx_ma(i) = find(d2==min(d2));
                    %% Second derivative of fitted curve
                end
            clear kernel F_filter dl_filter gof opts d2 ft i xData yData rmse
            %% Double treshhold hysteresis
            [app.wlvalue]=app.double_t_h_hysteresis(idx_ma,wlma);
            clear idx
            %% Applying the filter to data
            kernel=ones(app.wlvalue,1)/app.wlvalue;
            F_filter=filter(kernel, 1, F);
            dl_filter=filter(kernel, 1, dl);
            clear wlvalue
        end
        
        function [wlvalue]=double_t_h_hysteresis(app,idx,wl)
            t_h=median(idx)+(((max(idx)-median(idx))/(max(idx)*2))*median(idx));%t_h-higher treshold
            t_l=median(idx)-(((max(idx)-median(idx))/(max(idx)*2))*median(idx));%t_l-lower treshold
            ids=(idx<t_h) & (idx>t_l);
            idx_th_tl=idx(ids);
            wl_th_tl=wl(ids);
            wlvalue=wl_th_tl(1);
            clear wl_th_tl idx_th_tl ids t_l t_h  
        end
        
        function [F_filter_sg,dl_filter_sg]=SG_opt_filt(app,dl,F)
            Sp=0.9999999;
            h=length(app.F)-1;
            %wl_start-starting number for different window length, has to be odd
            %wl_step-step size for window length vector, has to be 2*n,n can be any
            %number, this makes sure that if the starting number is odd any other
            %number in array is also odd
            if h>10 && h<1000
                wlmax=floor(h/2);
                wl_start=floor(wlmax/10);
                if mod(wl_start,2)==0   
                    wl_start=wl_start+1 ; 
                else   
                end
                n=floor(wl_start/2);
                wlstep=2*n;
                wlsg=wl_start:wlstep:wlmax;
            elseif length(F)>=1000
                wlmax=floor(h/10);
                wl_start=floor(wlmax/10);
                if mod(wl_start,2)==0 
                    wl_start=wl_start+1; 
                else  
                end
                n=floor(wl_start/2);
                wlstep=2*n;
                wlsg=wl_start:wlstep:wlmax;
            else
                wlsg=1;
            end
            wlsg=wlsg';
            rmse_sg=zeros(1,length(wlsg));
            idx_sg=zeros(1,length(wlsg));
                for i=1:1:length(wlsg)
                    F_filt1 = sgolayfilt(F,3,wlsg(i));
                    dl_filt1=sgolayfilt(dl,3,wlsg(i));
                    rmse_sg(i)=(sqrt(mean((F-F_filt1).^2)));
                    [xData, yData] = prepareCurveData(dl_filt1, F_filt1);
                    ft = fittype( 'smoothingspline' );
                    opts = fitoptions( 'Method', 'SmoothingSpline' );
                    opts.SmoothingParam=Sp;
                    [fitresult,~] = fit( xData, yData, ft, opts );
                    [~,d2] = differentiate(fitresult,dl);
                    idx_sg(i) = find(d2==min(d2));           
                end
            clear F_filt1 dl_filt1 gof opts d2 ft i xData yData rmse_sg
            %% Double treshhold hysteresis
            [app.wlvalue]=app.double_t_h_hysteresis(idx_sg,wlsg);
            clear idx_sg
            F_filter_sg=sgolayfilt(F,3,app.wlvalue);
            dl_filter_sg=sgolayfilt(dl,3,app.wlvalue);
        end
        
        function [n2idxSSF,n2_meand1] = Smooth_Spline_fit_v2(app,F,dl)
           Sp=0.9999999;
           %% Smoothing Cubic Spline fitting of the given curve
           [xData, yData] = prepareCurveData( dl, F );
           % Set up fittype and options.
           ft = fittype( 'smoothingspline' );
           opts = fitoptions( 'Method', 'SmoothingSpline' );
           opts.SmoothingParam=Sp;
           % Fit model to data.
           [fitresult, ~] = fit( xData, yData, ft, opts );
           %% Smoothing Spline Derivatives
           [d1,d2] = differentiate(fitresult,dl);
            meand1=mean(d1);
           for i=1:1:length(d1)
               if(d1(i)>2*meand1) && d1(i)>0
                   n2_meand1=i;%n2_meand1- last point before derivative goes into mean values
               end
           end
           idx = find(d2==min(d2));
           n2idxSSF=idx;%n2idxSSF-n2 point for Smoothing Spline fit
           clear h idx i meand1 d1 d2 fitresult opts ft xData yData Sp
        end
        
        function [n2_theo_points]=n2_points_filter(app,n2idxSSF_ma,n2_meand1_ma,n2idxSSF_sg,n2_meand1_sg,n2idxFH,lenF)
            n2_theo_points(1)=n2idxSSF_ma;
            n2_theo_points(2)=n2_meand1_ma;
            n2_theo_points(3)=n2idxSSF_sg;
            n2_theo_points(4)=n2_meand1_sg;
            n2_theo_points(5)=n2idxFH;
            %% filter
            rowstodelete= n2_theo_points>(lenF/2);
            n2_theo_points(rowstodelete)=[];
            n2_theo_points(isnan(n2_theo_points))=[];
        end
        
        function [dl,F]=inverse_of_data_normalization(app,dl,F,normalization_data)
            dl=(dl*(normalization_data(2)-normalization_data(1)))+normalization_data(1);
            F=(F*(normalization_data(4)-normalization_data(3)))+normalization_data(3);
        end
        
        function [Stress,Strain]=material_testing_dimensions_input(app,F,dl)
            if app.t>0 && app.b >0
                Stress=((F.*1000)./(app.t*app.b));%MPa
            elseif app.d>0
                Stress=((F.*1000)./((pi*((app.d)^2))/4));%MPa
            else
                Stress=F;
            end
            Strain=(dl./app.L0)*100;
            app.pltval=2;
        end
        
        function [opt_rp_line,slope]=Optimal_line_fit_v2_AG(app,F,dl,n2_theo_points)
                for j=1:1:length(n2_theo_points)
    
                n=1:1:n2_theo_points(j);
                    for i=1:1:length(n)
                        fit=@(x)fitline2(x,dl,F,n(i));
                        x0=1;
                        bestfit(i)= fminsearch(fit,x0);
                        error(i)=(sum(abs(F(1:n(i))-(bestfit(i)*dl(1:n(i))))))/n(i);
                        error2(i)=sqrt(sum((F(1:n(i))-(bestfit(i)*dl(1:n(i)))).^2)/n(i));
                    end
                Medianerror1=(median(error));
                Medianerror2=(median(error2));
                
                mat1=(abs(error-Medianerror1));
                min_mat1=(min(mat1));
                opt_idx_abs_error=find(mat1==min_mat1);%optimal index for absolute difference error
                bestlineabs(j)=bestfit(opt_idx_abs_error(1));
                least_err_abs(j)=error(opt_idx_abs_error(1));
                
                mat2=(abs(error2-(median(error2))));
                min_mat2=(min(mat2));
                opt_idx_rs_error=find(mat2==min_mat2);%optimal index for root square error
                bestliners(j)=bestfit(opt_idx_rs_error(1));
                least_err_rs(j)=error2(opt_idx_rs_error(1));
                
                clear n error error 2 Medianerror1 Medianerror2 mat1 mat2 min_mat1 min_mat2
                end
            %%
            bestline=[bestlineabs,bestliners];
            clear bestlineabs besliers
        
            least_err=[least_err_abs,least_err_rs];
            minbestlineIDX=find(least_err==min(least_err));
            clear least_err_abs least_err_abs
        
            %%  
            slope=bestline(minbestlineIDX);
            
            opt_rp_line=dl*slope;
            clear bestlineabs besliers least_err_abs least_err_abs j
        end
        
        function sse=fitline2(app,x,dl,F,n) %sends i-th index of n but takes in only int n
            z=x(1);%optimization variable
            sse=(1/n)*(sum(sqrt(((z.*dl(1:n)-F(1:n))).^2)));%cost function from 1 to n
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.AcquiredatapointsButton.Enable='off';
            app.ConversiontoStressStrainButton.Enable='off';
            app.PlotDataButton.Enable='off';
            app.PlotRp01Button.Enable='off';
            app.PlotRp02Button.Enable='off';
        end

        % Button pushed function: LoadDatasetButton
        function LoadDatasetButtonPushed(app, event)
            opts = delimitedTextImportOptions("NumVariables", 10);
            %% Specify range and delimiter
            opts.DataLines = [5, Inf];
            opts.Delimiter = ",";
            %% Specify column names and types
            opts.VariableNames = ["Notes", "Time", "Sequence", "Block", "Cycle","CMDffs", "Positionmm", "LoadkN", "Strain1uzduznimm", "Strain2poprecniVolts"];
            opts.VariableTypes = ["string", "double", "double", "double", "string", "double", "double", "double", "double", "double"];
            opts = setvaropts(opts, [1, 5], "WhitespaceRule", "preserve");
            opts = setvaropts(opts, [1, 5], "EmptyFieldRule", "auto");
            opts.ExtraColumnsRule = "ignore";
            opts.EmptyLineRule = "read";
            [app.filename,app.path]=uigetfile('*.csv');
            f=fullfile(app.path,app.filename);
            if f==1
                app.Lamp.Color=[1 0.74 0.13];
            else
                app.Lamp.Color=[0.1 0.73 0.01];
                app.Dataset = readtable(f, opts);
                clear opts
                app.F=app.Dataset{:,'LoadkN'};
                app.dl=app.Dataset{:,'Positionmm'};
                app.T=app.Dataset{:,'Time'};
            end
            app.vector=app.Dataset{:,'Strain1uzduznimm'};
            app.value1 = app.Extensometer_on_test(app.vector);
            if app.value1 ==1
                app.dl=app.Dataset{:,'Strain1uzduznimm'};
                app.Lamp_4.Color=[0.1 0.73 0.01];
            else
                clear ex1
                app.Lamp_4.Color=[1 0.74 0.13];
            end
            clear vector
            app.pltval=1;
            app.AcquiredatapointsButton.Enable='on';
            app.PlotDataButton.Enable='on';
        end

        % Value changed function: ThicknessmmEditField
        function ThicknessmmEditFieldValueChanged(app, event)
            app.t = app.ThicknessmmEditField.Value;
        end

        % Value changed function: WidthmmEditField
        function WidthmmEditFieldValueChanged(app, event)
            app.b = app.WidthmmEditField.Value; 
        end

        % Value changed function: LengthmmEditField
        function LengthmmEditFieldValueChanged(app, event)
            app.L0 = app.LengthmmEditField.Value;
            if app.L0>0 && app.t>0
                app.Lamp_2.Color=[0.93 1 0.38];
            elseif app.L0>0 && app.d>0
                app.Lamp_2.Color=[0.93 1 0.38];
            else
                app.Lamp_2.Color=[1 0.74 0.13];
            end
        end

        % Button pushed function: AcquiredatapointsButton
        function AcquiredatapointsButtonPushed(app, event)

            [app.F,app.dl,app.n3,app.fs,app.fn] = app.Data_preprocessing_v1(app.F,app.T,app.dl);
            [app.norm_data,app.dl,app.F]=app.data_normalization(app.dl,app.F);
            [app.n2idxFH] = app.Filter_heuristic_v1(app.F,app.dl,app.fs);
            [app.F_filter_ma,app.dl_filter_ma]=app.MA_opt_filt(app.dl,app.F);
            [app.F_filter_sg,app.dl_filter_sg]=app.SG_opt_filt(app.dl,app.F);
            [app.n2idxSSF_ma,app.n2_meand1_ma] = app.Smooth_Spline_fit_v2(app.F_filter_ma,app.dl_filter_ma);
            [app.n2idxSSF_sg,app.n2_meand1_sg] = app.Smooth_Spline_fit_v2(app.F_filter_sg,app.dl_filter_sg);
            app.lenF=length(app.F);
            [app.n2_theo_points]=app.n2_points_filter(app.n2idxSSF_ma,app.n2_meand1_ma,app.n2idxSSF_sg,app.n2_meand1_sg,app.n2idxFH,app.lenF);
            [app.dl,app.F]=app.inverse_of_data_normalization(app.dl,app.F,app.norm_data);
            if app.t > 0 && app.L0 > 0
                app.ConversiontoStressStrainButton.Enable='on';
                app.Lamp_2.Color=[0.1 0.73 0.01];
            elseif app.d> 0 && app.L0 > 0
                app.ConversiontoStressStrainButton.Enable='on';
                app.Lamp_2.Color=[0.1 0.73 0.01];
            else 
            end
            app.Lamp_3.Color=[0.47 0.67 0.19];
        end

        % Button pushed function: PlotDataButton
        function PlotDataButtonPushed(app, event)

            if app.pltval==2
                hold(app.UIAxes,'on')
                plot(app.UIAxes,app.Strain,app.Stress,'k-')
                app.UIAxes.Title.String=app.filename;
                app.UIAxes.XLabel.String='Strain (%)';
                app.UIAxes.YLabel.String='Stress (MPa)';
                hold(app.UIAxes,'off')

            elseif app.pltval==1
                plot(app.UIAxes,app.dl,app.F,'k-')
                app.UIAxes.Title.String=app.filename;
                app.UIAxes.XLabel.String='Position (%)';
                app.UIAxes.YLabel.String='Load (kN)';
            else
            end
            [app.opt_rp_line,app.slope]=app.Optimal_line_fit_v2_AG(app.Stress,app.Strain,app.n2_theo_points);
            max_opt_rp_line_idx=find(app.opt_rp_line<max(app.Stress), 1, 'last' );
            hold(app.UIAxes,'on')
            plot(app.UIAxes,app.Strain(1:max_opt_rp_line_idx),app.opt_rp_line(1:max_opt_rp_line_idx),'r-')
            hold(app.UIAxes,'off')
            app.PlotRp01Button.Enable='on';
            app.PlotRp02Button.Enable='on';
        end

        % Value changed function: SpecimenDropDown
        function SpecimenDropDownValueChanged(app, event)
           app.dimvalue= app.SpecimenDropDown.Value;
           if strcmp(app.dimvalue,'Rectangular')
               app.ThicknessmmEditField.Editable='on';
               app.WidthmmEditField.Editable='on';
               app.DiametermmEditField.Editable='off';
               app.Lamp.Color=[0.1 0.73 0.01];
           else
               app.DiametermmEditField.Editable='on';
               app.ThicknessmmEditField.Editable='off';
               app.WidthmmEditField.Editable='off';
               app.Lamp.Color=[0.1 0.73 0.01];
           end
        end

        % Value changed function: DiametermmEditField
        function DiametermmEditFieldValueChanged(app, event)
            app.d = app.DiametermmEditField.Value;
        end

        % Button pushed function: ConversiontoStressStrainButton
        function ConversiontoStressStrainButtonPushed(app, event)
            [app.Stress,app.Strain]=app.material_testing_dimensions_input(app.F,app.dl);
            app.pltval=2;
        end

        % Callback function
        function RpDropDownValueChanged(app, event)
            app.pltrpval = app.RpDropDown.Value;
            if strcmp(app.pltrpval,'0.2%')
                app.pltrpval=2;
            elseif strcmp(app.pltrpval,'Both')
                app.pltrpval=3;
            elseif strcmp(app.pltrpval,'0.1%')
                app.pltrpval=1;
            else
                app.pltrpval=1;
            end
        end

        % Button pushed function: PlotRp01Button
        function PlotRp01ButtonPushed(app, event)
               lx01=-0.1*app.slope;
               app.proof_line01=app.opt_rp_line+lx01;
               min_proof_line_idx01=find(abs(app.proof_line01)==min(abs(app.proof_line01)));
               max_proof_line_idx01=find(abs(app.proof_line01)<max(app.Stress), 1, 'last' );
               hold(app.UIAxes,'on')
               plot(app.UIAxes,app.Strain(min_proof_line_idx01:max_proof_line_idx01),app.proof_line01(min_proof_line_idx01:max_proof_line_idx01),'g-','LineWidth',2);
               hold(app.UIAxes,'off')
               app.PlotRp01Button.Enable='off';
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {646, 646};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: PlotRp02Button
        function PlotRp02ButtonPushed(app, event)
               lx02=-0.2*app.slope;
               app.proof_line02=app.opt_rp_line+lx02;
               min_proof_line_idx02=find(abs(app.proof_line02)==min(abs(app.proof_line02)));
               max_proof_line_idx02=find(abs(app.proof_line02)<max(app.Stress), 1, 'last' );
               hold(app.UIAxes,'on')
               plot(app.UIAxes,app.Strain(min_proof_line_idx02:max_proof_line_idx02),app.proof_line02(min_proof_line_idx02:max_proof_line_idx02),'b-','LineWidth',2);
               hold(app.UIAxes,'off')
               app.PlotRp02Button.Enable='off';
        end

        % Button pushed function: ClearplotButton
        function ClearplotButtonPushed(app, event)
            app.UIAxes.cla
            app.PlotRp01Button.Enable='on';
            app.PlotRp02Button.Enable='on';
        end

        % Button pushed function: NewAnalysisButton
        function NewAnalysisButtonPushed(app, event)
            app.UIAxes.cla
            startupFcn(app)
            app.Lamp.Color=[0.94 0.94 0.94];
            app.Lamp_2.Color=[1 0 0 ];
            app.Lamp_3.Color=[1 0 0 ];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LoadDatasetButton = uibutton(app.LeftPanel, 'push');
            app.LoadDatasetButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDatasetButtonPushed, true);
            app.LoadDatasetButton.Text = 'Load Dataset';
            app.Lamp = uilamp(app.LeftPanel);
            app.AcquiredatapointsButton = uibutton(app.LeftPanel, 'push');
            app.AcquiredatapointsButton.ButtonPushedFcn = createCallbackFcn(app, @AcquiredatapointsButtonPushed, true);
            app.AcquiredatapointsButton.Text = 'Acquire data points';
            app.TestspecimendimensionsLabel = uilabel(app.LeftPanel);
            app.TestspecimendimensionsLabel.Text = 'Test specimen dimensions:';
            app.ThicknessmmEditFieldLabel = uilabel(app.LeftPanel);
            app.ThicknessmmEditFieldLabel.Text = 'Thickness(mm)';
            app.ThicknessmmEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ThicknessmmEditField.ValueChangedFcn = createCallbackFcn(app, @ThicknessmmEditFieldValueChanged, true);
            app.WidthmmEditFieldLabel = uilabel(app.LeftPanel);
            app.WidthmmEditFieldLabel.Text = 'Width(mm)';
            app.WidthmmEditField = uieditfield(app.LeftPanel, 'numeric');
            app.WidthmmEditField.ValueChangedFcn = createCallbackFcn(app, @WidthmmEditFieldValueChanged, true);
            app.LengthmmEditFieldLabel = uilabel(app.LeftPanel);
            app.LengthmmEditFieldLabel.Text = 'Length(mm)';
            app.LengthmmEditField = uieditfield(app.LeftPanel, 'numeric');
            app.LengthmmEditField.ValueChangedFcn = createCallbackFcn(app, @LengthmmEditFieldValueChanged, true);
            app.stStepLabel = uilabel(app.LeftPanel);
            app.stStepLabel.Text = '1st Step';
            app.ndStepLabel = uilabel(app.LeftPanel);
            app.ndStepLabel.Text = '2nd Step';
            app.rdStepLabel = uilabel(app.LeftPanel);
            app.rdStepLabel.Text = '3rd Step';
            app.SpecimenDropDownLabel = uilabel(app.LeftPanel);
            app.SpecimenDropDownLabel.Text = 'Specimen';
            app.SpecimenDropDown = uidropdown(app.LeftPanel);
            app.SpecimenDropDown.Items = {'Rectangular', 'Circular'};
            app.SpecimenDropDown.ValueChangedFcn = createCallbackFcn(app, @SpecimenDropDownValueChanged, true);
            app.SpecimenDropDown.Value = 'Rectangular';
            app.DiametermmEditFieldLabel = uilabel(app.LeftPanel);
            app.DiametermmEditFieldLabel.Text = 'Diameter(mm)';
            app.DiametermmEditField = uieditfield(app.LeftPanel, 'numeric');
            app.DiametermmEditField.ValueChangedFcn = createCallbackFcn(app, @DiametermmEditFieldValueChanged, true);
            app.DiametermmEditField.Editable = 'off';
            app.Lamp_2 = uilamp(app.LeftPanel);
            app.Lamp_3 = uilamp(app.LeftPanel);
            app.PlotDataButton = uibutton(app.LeftPanel, 'push');
            app.PlotDataButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDataButtonPushed, true);
            app.PlotDataButton.Text = 'Plot Data';
            app.Lamp_4 = uilamp(app.LeftPanel);
            app.ExtensometerengagedLabel = uilabel(app.LeftPanel);
            app.ExtensometerengagedLabel.Text = 'Extensometer engaged';
            app.ConversiontoStressStrainButton = uibutton(app.LeftPanel, 'push');
            app.ConversiontoStressStrainButton.ButtonPushedFcn = createCallbackFcn(app, @ConversiontoStressStrainButtonPushed, true);
            app.ConversiontoStressStrainButton.Text = 'Conversion to Stress&Strain';
            app.RightPanel = uipanel(app.GridLayout);
            app.PlotRp01Button = uibutton(app.RightPanel, 'push');
            app.PlotRp01Button.ButtonPushedFcn = createCallbackFcn(app, @PlotRp01ButtonPushed, true);
            app.PlotRp01Button.Text = 'Plot Rp0.1';
            app.PlotRp02Button = uibutton(app.RightPanel, 'push');
            app.PlotRp02Button.ButtonPushedFcn = createCallbackFcn(app, @PlotRp02ButtonPushed, true);
            app.PlotRp02Button.Text = 'Plot Rp0.2';
            app.ClearplotButton = uibutton(app.RightPanel, 'push');
            app.ClearplotButton.ButtonPushedFcn = createCallbackFcn(app, @ClearplotButtonPushed, true);
            app.ClearplotButton.Text = 'Clear plot';
            app.NewAnalysisButton = uibutton(app.RightPanel, 'push');
            app.NewAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @NewAnalysisButtonPushed, true);
            app.NewAnalysisButton.Text = 'New Analysis';
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MSAPP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end